classdef mmWaveStudioMATLAB_GUI_jws < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ConfigTab                     matlab.ui.container.Tab
        InitializemmWaveStudioPanel   matlab.ui.container.Panel
        InitializeButton              matlab.ui.control.Button
        InitializeLamp                matlab.ui.control.Lamp
        RadarConfigurationPanel       matlab.ui.container.Panel
        StartFreqGHzEditFieldLabel    matlab.ui.control.Label
        StartFreqGHzEditField         matlab.ui.control.NumericEditField
        FreqSlopeMHzusEditFieldLabel  matlab.ui.control.Label
        FreqSlopeMHzusEditField       matlab.ui.control.NumericEditField
        IdleTimeusEditFieldLabel      matlab.ui.control.Label
        IdleTimeusEditField           matlab.ui.control.NumericEditField
        TXStartTimeusEditFieldLabel   matlab.ui.control.Label
        TXStartTimeusEditField        matlab.ui.control.NumericEditField
        ADCStartTimeusEditFieldLabel  matlab.ui.control.Label
        ADCStartTimeusEditField       matlab.ui.control.NumericEditField
        ADCSamplesEditFieldLabel      matlab.ui.control.Label
        ADCSamplesEditField           matlab.ui.control.NumericEditField
        SampleRatekspsEditFieldLabel  matlab.ui.control.Label
        SampleRatekspsEditField       matlab.ui.control.NumericEditField
        RampEndTimeusEditFieldLabel   matlab.ui.control.Label
        RampEndTimeusEditField        matlab.ui.control.NumericEditField
        NoofFramesEditFieldLabel      matlab.ui.control.Label
        NoofFramesEditField           matlab.ui.control.NumericEditField
        NoofChirpLoopsEditFieldLabel  matlab.ui.control.Label
        NoofChirpLoopsEditField       matlab.ui.control.NumericEditField
        PeriodicitymsEditFieldLabel   matlab.ui.control.Label
        PeriodicitymsEditField        matlab.ui.control.NumericEditField
        ConfigureRadarButton          matlab.ui.control.Button
        Rx0CheckBox                   matlab.ui.control.CheckBox
        Rx1CheckBox                   matlab.ui.control.CheckBox
        Rx3CheckBox                   matlab.ui.control.CheckBox
        Rx2CheckBox                   matlab.ui.control.CheckBox
        Tx0CheckBox                   matlab.ui.control.CheckBox
        Tx1CheckBox                   matlab.ui.control.CheckBox
        Tx2CheckBox                   matlab.ui.control.CheckBox
        ConfigureRadarLamp            matlab.ui.control.Lamp
        PhysicalElementsUIAxes        matlab.ui.control.UIAxes
        VirtualElementsUIAxes         matlab.ui.control.UIAxes
        Image2                        matlab.ui.control.Image
        DisconnectOptionalPanel       matlab.ui.container.Panel
        DisconnectRadarButton         matlab.ui.control.Button
        DisconnectDCAButton           matlab.ui.control.Button
        ChirpCharacteristicsPanel     matlab.ui.container.Panel
        DutyCycleEditFieldLabel       matlab.ui.control.Label
        DutyCycleEditField            matlab.ui.control.NumericEditField
        MaxRangemEditFieldLabel       matlab.ui.control.Label
        MaxRangemEditField            matlab.ui.control.NumericEditField
        BandwidthGHzEditFieldLabel    matlab.ui.control.Label
        BandwidthGHzEditField         matlab.ui.control.NumericEditField
        RangeResmEditFieldLabel       matlab.ui.control.Label
        RangeResmEditField            matlab.ui.control.NumericEditField
        mmWaveStudioLamp              matlab.ui.control.Lamp
        mmWaveStudioTextAreaLabel     matlab.ui.control.Label
        mmWaveStudioTextArea          matlab.ui.control.TextArea
        ConnecttommWaveStudioButton   matlab.ui.control.Button
        mmWaveStudioInterfaceLabel    matlab.ui.control.Label
        StopFrameButton               matlab.ui.control.Button
        TriggerFrameButton            matlab.ui.control.Button
        JosiahWSmithLabel             matlab.ui.control.Label
    end

    properties (Access = public)
        %% Initialize Variables
        isConnectedmmWaveStudio;
        isCaptureActive;
        isInitialize;
        isRadarConfigure;
        isDataReader;
        
        Params;
        COMPort;
        imMethod
        
        %% Initialize Paths
        RSTD_DLL_Path = 'C:\ti\mmwave_studio_02_01_00_00\mmWaveStudio\Clients\RtttNetClientController\RtttNetClientAPI.dll';
        %RSTD_DLL_Path = 'D:\ti\mmwave_studio_01_00_00_00\mmWaveStudio\Clients\RtttNetClientController\RtttNetClientAPI.dll';
        
        % NOTE: Some computers need different versions. I have not figured
        % out why that is yet. For example, Josiah's desktop likes mmWave
        % Studio 2.1.0.0 but does not like mmWave Studio 1.0.0.0, but
        % Josiah's laptop is the opposite. Use the version that works for
        % you or specify your own. 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Add Necessary Directories
            addpath(genpath("./"))
            
            %% Initialize Values
            app.isConnectedmmWaveStudio = false;
            app.isCaptureActive = false;
            app.isInitialize = false;
            
            %% Initialize Params & p
            updateAll(app);
            
            %% Initialize Params
            app.Params.z0_mm = 250; % average target distance in mm
            
            %% Initialize
            AWR1243_ArrayDimensions_GUI(app);
        end

        % Button pushed function: ConnecttommWaveStudioButton
        function ConnecttommWaveStudioButtonPushed(app, event)
            Connect_mmWaveStudio(app);
        end

        % Value changed function: Tx2CheckBox
        function Tx2CheckBoxValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: Tx1CheckBox
        function Tx1CheckBoxValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: StartFreqGHzEditField
        function StartFreqGHzEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: FreqSlopeMHzusEditField
        function FreqSlopeMHzusEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: IdleTimeusEditField
        function IdleTimeusEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: TXStartTimeusEditField
        function TXStartTimeusEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: ADCStartTimeusEditField
        function ADCStartTimeusEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: ADCSamplesEditField
        function ADCSamplesEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: SampleRatekspsEditField
        function SampleRatekspsEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: RampEndTimeusEditField
        function RampEndTimeusEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: NoofFramesEditField
        function NoofFramesEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: NoofChirpLoopsEditField
        function NoofChirpLoopsEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: PeriodicitymsEditField
        function PeriodicitymsEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            initializemmWaveStudio(app);
        end

        % Button pushed function: ConfigureRadarButton
        function ConfigureRadarButtonPushed(app, event)
            configureRadar(app);
        end

        % Button pushed function: TriggerFrameButton
        function TriggerFrameButtonPushed(app, event)
            triggerFrame(app);
        end

        % Button pushed function: StopFrameButton
        function StopFrameButtonPushed(app, event)
            stopFrame(app);
        end

        % Button pushed function: DisconnectRadarButton
        function DisconnectRadarButtonPushed(app, event)
            disconnectRadar(app);
        end

        % Button pushed function: DisconnectDCAButton
        function DisconnectDCAButtonPushed(app, event)
            disconnectDCA(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.InitializemmWaveStudioPanel = uipanel(app.ConfigTab);
            app.InitializemmWaveStudioPanel.Title = '1. Initialize mmWave Studio';
            app.InitializeButton = uibutton(app.InitializemmWaveStudioPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.InitializeLamp = uilamp(app.InitializemmWaveStudioPanel);
            app.RadarConfigurationPanel = uipanel(app.ConfigTab);
            app.RadarConfigurationPanel.Title = '2. Radar Configuration';
            app.StartFreqGHzEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.StartFreqGHzEditFieldLabel.Text = 'Start Freq (GHz)';
            app.StartFreqGHzEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.StartFreqGHzEditField.ValueDisplayFormat = '%11.3f';
            app.StartFreqGHzEditField.ValueChangedFcn = createCallbackFcn(app, @StartFreqGHzEditFieldValueChanged, true);
            app.StartFreqGHzEditField.Value = 77;
            app.FreqSlopeMHzusEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.FreqSlopeMHzusEditFieldLabel.Text = 'Freq Slope (MHz/us)';
            app.FreqSlopeMHzusEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.FreqSlopeMHzusEditField.ValueDisplayFormat = '%11.3f';
            app.FreqSlopeMHzusEditField.ValueChangedFcn = createCallbackFcn(app, @FreqSlopeMHzusEditFieldValueChanged, true);
            app.FreqSlopeMHzusEditField.Value = 100.036;
            app.IdleTimeusEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.IdleTimeusEditFieldLabel.Text = 'Idle Time (us)';
            app.IdleTimeusEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.IdleTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.IdleTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @IdleTimeusEditFieldValueChanged, true);
            app.IdleTimeusEditField.Value = 10;
            app.TXStartTimeusEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.TXStartTimeusEditFieldLabel.Text = 'TX Start Time (us)';
            app.TXStartTimeusEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.TXStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.TXStartTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @TXStartTimeusEditFieldValueChanged, true);
            app.ADCStartTimeusEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.ADCStartTimeusEditFieldLabel.Text = 'ADC Start Time (us)';
            app.ADCStartTimeusEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.ADCStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.ADCStartTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @ADCStartTimeusEditFieldValueChanged, true);
            app.ADCSamplesEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.ADCSamplesEditFieldLabel.Text = 'ADC Samples';
            app.ADCSamplesEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.ADCSamplesEditField.Limits = [0 Inf];
            app.ADCSamplesEditField.RoundFractionalValues = 'on';
            app.ADCSamplesEditField.ValueDisplayFormat = '%11.3g';
            app.ADCSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @ADCSamplesEditFieldValueChanged, true);
            app.ADCSamplesEditField.Value = 79;
            app.SampleRatekspsEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.SampleRatekspsEditFieldLabel.Text = 'Sample Rate (ksps)';
            app.SampleRatekspsEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.SampleRatekspsEditField.Limits = [2000 37500];
            app.SampleRatekspsEditField.ValueDisplayFormat = '%11.3f';
            app.SampleRatekspsEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRatekspsEditFieldValueChanged, true);
            app.SampleRatekspsEditField.Value = 2000;
            app.RampEndTimeusEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.RampEndTimeusEditFieldLabel.Text = 'Ramp End Time (us)';
            app.RampEndTimeusEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.RampEndTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.RampEndTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @RampEndTimeusEditFieldValueChanged, true);
            app.RampEndTimeusEditField.Value = 39.98;
            app.NoofFramesEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.NoofFramesEditFieldLabel.Text = 'No of Frames';
            app.NoofFramesEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.NoofFramesEditField.ValueDisplayFormat = '%11.3g';
            app.NoofFramesEditField.ValueChangedFcn = createCallbackFcn(app, @NoofFramesEditFieldValueChanged, true);
            app.NoofChirpLoopsEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.NoofChirpLoopsEditFieldLabel.Text = 'No of Chirp Loops';
            app.NoofChirpLoopsEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.NoofChirpLoopsEditField.ValueDisplayFormat = '%11.3g';
            app.NoofChirpLoopsEditField.ValueChangedFcn = createCallbackFcn(app, @NoofChirpLoopsEditFieldValueChanged, true);
            app.NoofChirpLoopsEditField.Value = 1;
            app.PeriodicitymsEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.PeriodicitymsEditFieldLabel.Text = 'Periodicity (ms)';
            app.PeriodicitymsEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.PeriodicitymsEditField.ValueDisplayFormat = '%11.3f';
            app.PeriodicitymsEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodicitymsEditFieldValueChanged, true);
            app.PeriodicitymsEditField.Value = 5;
            app.ConfigureRadarButton = uibutton(app.RadarConfigurationPanel, 'push');
            app.ConfigureRadarButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureRadarButtonPushed, true);
            app.ConfigureRadarButton.Text = 'Configure Radar';
            app.Rx0CheckBox = uicheckbox(app.RadarConfigurationPanel);
            app.Rx0CheckBox.Enable = 'off';
            app.Rx0CheckBox.Text = 'Rx0';
            app.Rx0CheckBox.Value = true;
            app.Rx1CheckBox = uicheckbox(app.RadarConfigurationPanel);
            app.Rx1CheckBox.Enable = 'off';
            app.Rx1CheckBox.Text = 'Rx1';
            app.Rx1CheckBox.Value = true;
            app.Rx3CheckBox = uicheckbox(app.RadarConfigurationPanel);
            app.Rx3CheckBox.Enable = 'off';
            app.Rx3CheckBox.Text = 'Rx3';
            app.Rx3CheckBox.Value = true;
            app.Rx2CheckBox = uicheckbox(app.RadarConfigurationPanel);
            app.Rx2CheckBox.Enable = 'off';
            app.Rx2CheckBox.Text = 'Rx2';
            app.Rx2CheckBox.Value = true;
            app.Tx0CheckBox = uicheckbox(app.RadarConfigurationPanel);
            app.Tx0CheckBox.Enable = 'off';
            app.Tx0CheckBox.Text = 'Tx0';
            app.Tx0CheckBox.Value = true;
            app.Tx1CheckBox = uicheckbox(app.RadarConfigurationPanel);
            app.Tx1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Tx1CheckBoxValueChanged, true);
            app.Tx1CheckBox.Text = 'Tx1';
            app.Tx2CheckBox = uicheckbox(app.RadarConfigurationPanel);
            app.Tx2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Tx2CheckBoxValueChanged, true);
            app.Tx2CheckBox.Text = 'Tx2';
            app.Tx2CheckBox.Value = true;
            app.ConfigureRadarLamp = uilamp(app.RadarConfigurationPanel);
            app.PhysicalElementsUIAxes = uiaxes(app.ConfigTab);
            title(app.PhysicalElementsUIAxes, 'Title')
            xlabel(app.PhysicalElementsUIAxes, 'X')
            ylabel(app.PhysicalElementsUIAxes, 'Y')
            app.VirtualElementsUIAxes = uiaxes(app.ConfigTab);
            title(app.VirtualElementsUIAxes, 'Title')
            xlabel(app.VirtualElementsUIAxes, 'X')
            ylabel(app.VirtualElementsUIAxes, 'Y')
            app.Image2 = uiimage(app.ConfigTab);
            app.Image2.ImageSource = 'tiChirpDiagram.png';
            app.DisconnectOptionalPanel = uipanel(app.ConfigTab);
            app.DisconnectOptionalPanel.Title = 'Disconnect (Optional)';
            app.DisconnectRadarButton = uibutton(app.DisconnectOptionalPanel, 'push');
            app.DisconnectRadarButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectRadarButtonPushed, true);
            app.DisconnectRadarButton.Text = 'Disconnect Radar';
            app.DisconnectDCAButton = uibutton(app.DisconnectOptionalPanel, 'push');
            app.DisconnectDCAButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectDCAButtonPushed, true);
            app.DisconnectDCAButton.Text = 'Disconnect DCA';
            app.ChirpCharacteristicsPanel = uipanel(app.ConfigTab);
            app.ChirpCharacteristicsPanel.Title = 'Chirp Characteristics';
            app.DutyCycleEditFieldLabel = uilabel(app.ChirpCharacteristicsPanel);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle (%)';
            app.DutyCycleEditField = uieditfield(app.ChirpCharacteristicsPanel, 'numeric');
            app.DutyCycleEditField.Editable = 'off';
            app.MaxRangemEditFieldLabel = uilabel(app.ChirpCharacteristicsPanel);
            app.MaxRangemEditFieldLabel.Text = 'Max Range (m)';
            app.MaxRangemEditField = uieditfield(app.ChirpCharacteristicsPanel, 'numeric');
            app.MaxRangemEditField.Editable = 'off';
            app.BandwidthGHzEditFieldLabel = uilabel(app.ChirpCharacteristicsPanel);
            app.BandwidthGHzEditFieldLabel.Text = 'Bandwidth (GHz)';
            app.BandwidthGHzEditField = uieditfield(app.ChirpCharacteristicsPanel, 'numeric');
            app.BandwidthGHzEditField.Editable = 'off';
            app.RangeResmEditFieldLabel = uilabel(app.ChirpCharacteristicsPanel);
            app.RangeResmEditFieldLabel.Text = 'Range Res (m)';
            app.RangeResmEditField = uieditfield(app.ChirpCharacteristicsPanel, 'numeric');
            app.RangeResmEditField.Editable = 'off';
            app.mmWaveStudioLamp = uilamp(app.UIFigure);
            app.mmWaveStudioTextAreaLabel = uilabel(app.UIFigure);
            app.mmWaveStudioTextAreaLabel.Text = 'mmWave Studio';
            app.mmWaveStudioTextArea = uitextarea(app.UIFigure);
            app.ConnecttommWaveStudioButton = uibutton(app.UIFigure, 'push');
            app.ConnecttommWaveStudioButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttommWaveStudioButtonPushed, true);
            app.ConnecttommWaveStudioButton.Text = 'Connect to mmWave Studio';
            app.mmWaveStudioInterfaceLabel = uilabel(app.UIFigure);
            app.mmWaveStudioInterfaceLabel.Text = 'mmWave Studio Interface';
            app.StopFrameButton = uibutton(app.UIFigure, 'push');
            app.StopFrameButton.ButtonPushedFcn = createCallbackFcn(app, @StopFrameButtonPushed, true);
            app.StopFrameButton.Text = 'Stop Frame';
            app.TriggerFrameButton = uibutton(app.UIFigure, 'push');
            app.TriggerFrameButton.ButtonPushedFcn = createCallbackFcn(app, @TriggerFrameButtonPushed, true);
            app.TriggerFrameButton.Text = 'Trigger Frame';
            app.JosiahWSmithLabel = uilabel(app.UIFigure);
            app.JosiahWSmithLabel.Text = 'Josiah W. Smith';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mmWaveStudioMATLAB_GUI_jws

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end